classdef MSIT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Main_GridLayout                matlab.ui.container.GridLayout
        KB2_GridLayout                 matlab.ui.container.GridLayout
        AdvanceKey                     matlab.ui.control.EditField
        AdvanceKey_Label               matlab.ui.control.Label
        QuitKey                        matlab.ui.control.EditField
        QuitKey_Label                  matlab.ui.control.Label
        KB_GridLayout                  matlab.ui.container.GridLayout
        MiddleKey                      matlab.ui.control.EditField
        LeftKey                        matlab.ui.control.EditField
        LeftKey_Label                  matlab.ui.control.Label
        MiddleKey_Label                matlab.ui.control.Label
        RightKey                       matlab.ui.control.EditField
        RightKey_Label                 matlab.ui.control.Label
        FileSettings_Label             matlab.ui.control.Label
        DataIOSettings_Label           matlab.ui.control.Label
        TTL_GridLayout                 matlab.ui.container.GridLayout
        TTLs_Label                     matlab.ui.control.Label
        TTLLength_EditField            matlab.ui.control.NumericEditField
        TTLLength_Label                matlab.ui.control.Label
        ParallelAddress_EditField      matlab.ui.control.EditField
        ParallelAddress_Label          matlab.ui.control.Label
        TTL_CheckBox                   matlab.ui.control.CheckBox
        DisplaySettings_Label          matlab.ui.control.Label
        BlockConfigurationFiles_Label  matlab.ui.control.Label
        Block4_GridLayout              matlab.ui.container.GridLayout
        Choose_Block4_File_Button      matlab.ui.control.Button
        Block_4_FileName               matlab.ui.control.Label
        Block_4_CheckBox               matlab.ui.control.CheckBox
        Block_4_Label                  matlab.ui.control.Label
        Block3_GridLayout              matlab.ui.container.GridLayout
        Choose_Block3_File_Button      matlab.ui.control.Button
        Block_3_FileName               matlab.ui.control.Label
        Block_3_CheckBox               matlab.ui.control.CheckBox
        Block_3_Label                  matlab.ui.control.Label
        Text_Settings_GridLayout       matlab.ui.container.GridLayout
        ResponseTime_EditField         matlab.ui.control.NumericEditField
        ResponseTime_Label             matlab.ui.control.Label
        IntroTextSize_EditField        matlab.ui.control.NumericEditField
        IntroTextSize_Label            matlab.ui.control.Label
        TextSize_EditField             matlab.ui.control.NumericEditField
        TextSize_Label                 matlab.ui.control.Label
        Task_Start_GridLayout          matlab.ui.container.GridLayout
        PushOnce_Label                 matlab.ui.control.Label
        ArmCamsWarning_Label           matlab.ui.control.Label
        Task_Start_Button              matlab.ui.control.StateButton
        File_Location_GridLayout       matlab.ui.container.GridLayout
        Change_File_Location_Button    matlab.ui.control.Button
        File_Location_Display          matlab.ui.control.Label
        File_Location_Label            matlab.ui.control.Label
        Patient_ID_GridLayout          matlab.ui.container.GridLayout
        Patient_ID_EditField           matlab.ui.control.EditField
        Patient_ID_Label               matlab.ui.control.Label
        Camera_GridLayout              matlab.ui.container.GridLayout
        MicrophoneChoice_Label         matlab.ui.control.Label
        MicrophoneChoice               matlab.ui.control.DropDown
        Microphone_CheckBox            matlab.ui.control.CheckBox
        EnableMicrophone_Label         matlab.ui.control.Label
        Camera_CheckBox                matlab.ui.control.CheckBox
        Cameras_Label                  matlab.ui.control.Label
        UDP_TTL_GridLayout             matlab.ui.container.GridLayout
        ShortBlockDemo_CheckBox        matlab.ui.control.CheckBox
        ShortBlockDemo_Label           matlab.ui.control.Label
        ScreenUsedNum                  matlab.ui.control.DropDown
        ScreenChoice_Label             matlab.ui.control.Label
        Block2_GridLayout              matlab.ui.container.GridLayout
        Choose_Block2_File_Button      matlab.ui.control.Button
        Block_2_FileName               matlab.ui.control.Label
        Block_2_CheckBox               matlab.ui.control.CheckBox
        Block_2_Label                  matlab.ui.control.Label
        Block1_GridLayout              matlab.ui.container.GridLayout
        Block_1_Label                  matlab.ui.control.Label
        Block_1_CheckBox               matlab.ui.control.CheckBox
        Choose_Block1_File_Button      matlab.ui.control.Button
        Block_1_FileName               matlab.ui.control.Label
    end

    
    properties (Access = public)
        FileSettings % Directory of the files used during the task
        ttlDevice    % parallel TTL device
        TaskSettings % task related settings
        TTLlog       % TTL log and settings
        PsyToolboxSettings; % psychtoolbox settings used
        TrialStruct % houses trial info for the current block
        FinishedTrials % houses complete trial results
        ExitFlag % Flag to save and quit after escape key is pressed
        udpObj % Camera control UDP object
        UDPnull % Camera UDP timeout flag
        CamsRunning % flag that cameras are running or not
        MicrophoneDevice % Microphone to use
        MicrophoneHandle % Microphone handle
    end
    
    methods (Access = public)
        MSIT_Main(app);
        FixationStart = MSIT_Run_Trial(app, lastFixationStart);
        FixationStart = MSIT_Show_Fixation(app);
        ShowTime = MSIT_Show_Text(app, text, isMain);
        MSIT_Wait_And_Get_Response(app, StartTime, WaitTime);
        MSIT_Wait_And_Advance_Or_Cancel(app, StartTime, WaitTime, Process);
        MSIT_Build_Trial_Struct(app);
        Setup_MSIT(app, Process);
        Display_Controls(app, Process);
        Send_Log_TTL(app, TTLTime, EventTxt, GoodAlign, WaitAfter);
        Save_All(app);
        Setup_Parallel_TTL(app, Process);
        Audio_Controls(app, Process);
        Camera_Controls(app, Process);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Display_Controls(app, 'find_screens');
            Setup_MSIT(app, 'initialize');
            Audio_Controls(app, 'setup');
            Setup_Parallel_TTL(app, 'toggle');
        end

        % Value changed function: Task_Start_Button
        function StartTheTask(app, event)
            try
                MSIT_Main(app);
            catch err
                disp('Error detected, saving everything...')

                % Stop the cameras
                Camera_Controls(app, 'stop');
            
                % Stop and save the audio
                Audio_Controls(app, 'stop');

                % Trying to save everything
                Save_All(app);

                try
                    % Close the live logs
                    fclose(app.FileSettings.TTL_live_log_ID);
                    fclose(app.FileSettings.Trials_live_log_ID);
                catch err
                    disp(err.message)
                end

                % Close screens
                Display_Controls(app, 'close');
                
                rethrow(err)
            end
        end

        % Button pushed function: Change_File_Location_Button, 
        % ...and 4 other components
        function ChooseCSVfile(app, event)
            Setup_MSIT(app, event.Source.Tag);
        end

        % Value changed function: TTL_CheckBox
        function UseTTL(app, event)
            Setup_Parallel_TTL(app, 'toggle');
        end

        % Value changed function: ShortBlockDemo_CheckBox
        function ShortBlockDemo_CheckBoxValueChanged(app, event)
            % Just call SetupParameters again, it will take care of
            % reassigning the files
            Setup_MSIT(app, 'initialize');
        end

        % Value changed function: MicrophoneChoice, Microphone_CheckBox
        function ChangeAudioSettings(app, event)
            Audio_Controls(app, 'setup');            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Main_GridLayout = uigridlayout(app.UIFigure);
            app.Block1_GridLayout = uigridlayout(app.Main_GridLayout);
            app.Block_1_FileName = uilabel(app.Block1_GridLayout);
            app.Block_1_FileName.Text = '';
            app.Choose_Block1_File_Button = uibutton(app.Block1_GridLayout, 'push');
            app.Choose_Block1_File_Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseCSVfile, true);
            app.Choose_Block1_File_Button.Tag = 'Block1FileButton';
            app.Choose_Block1_File_Button.Text = 'Choose File';
            app.Block_1_CheckBox = uicheckbox(app.Block1_GridLayout);
            app.Block_1_CheckBox.Text = '';
            app.Block_1_CheckBox.Value = true;
            app.Block_1_Label = uilabel(app.Block1_GridLayout);
            app.Block_1_Label.Text = 'Block One';
            app.Block2_GridLayout = uigridlayout(app.Main_GridLayout);
            app.Block_2_Label = uilabel(app.Block2_GridLayout);
            app.Block_2_Label.Text = 'Block Two';
            app.Block_2_CheckBox = uicheckbox(app.Block2_GridLayout);
            app.Block_2_CheckBox.Text = '';
            app.Block_2_CheckBox.Value = true;
            app.Block_2_FileName = uilabel(app.Block2_GridLayout);
            app.Block_2_FileName.Text = '';
            app.Choose_Block2_File_Button = uibutton(app.Block2_GridLayout, 'push');
            app.Choose_Block2_File_Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseCSVfile, true);
            app.Choose_Block2_File_Button.Tag = 'Block2FileButton';
            app.Choose_Block2_File_Button.Text = 'Choose File';
            app.UDP_TTL_GridLayout = uigridlayout(app.Main_GridLayout);
            app.ScreenChoice_Label = uilabel(app.UDP_TTL_GridLayout);
            app.ScreenChoice_Label.Text = 'Show task on screen';
            app.ScreenUsedNum = uidropdown(app.UDP_TTL_GridLayout);
            app.ScreenUsedNum.Items = {'...'};
            app.ScreenUsedNum.Value = '...';
            app.ShortBlockDemo_Label = uilabel(app.UDP_TTL_GridLayout);
            app.ShortBlockDemo_Label.Text = 'Do short block demo?';
            app.ShortBlockDemo_CheckBox = uicheckbox(app.UDP_TTL_GridLayout);
            app.ShortBlockDemo_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ShortBlockDemo_CheckBoxValueChanged, true);
            app.ShortBlockDemo_CheckBox.Text = '';
            app.Camera_GridLayout = uigridlayout(app.Main_GridLayout);
            app.Cameras_Label = uilabel(app.Camera_GridLayout);
            app.Cameras_Label.Text = 'Enable Cameras';
            app.Camera_CheckBox = uicheckbox(app.Camera_GridLayout);
            app.Camera_CheckBox.Text = '';
            app.Camera_CheckBox.Value = true;
            app.EnableMicrophone_Label = uilabel(app.Camera_GridLayout);
            app.EnableMicrophone_Label.Text = 'Enable Microphone';
            app.Microphone_CheckBox = uicheckbox(app.Camera_GridLayout);
            app.Microphone_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeAudioSettings, true);
            app.Microphone_CheckBox.Text = '';
            app.Microphone_CheckBox.Value = true;
            app.MicrophoneChoice = uidropdown(app.Camera_GridLayout);
            app.MicrophoneChoice.Items = {'...'};
            app.MicrophoneChoice.ValueChangedFcn = createCallbackFcn(app, @ChangeAudioSettings, true);
            app.MicrophoneChoice.Enable = 'off';
            app.MicrophoneChoice.Value = '...';
            app.MicrophoneChoice_Label = uilabel(app.Camera_GridLayout);
            app.MicrophoneChoice_Label.Enable = 'off';
            app.MicrophoneChoice_Label.Text = 'Microphone';
            app.Patient_ID_GridLayout = uigridlayout(app.Main_GridLayout);
            app.Patient_ID_Label = uilabel(app.Patient_ID_GridLayout);
            app.Patient_ID_Label.Text = 'Patient ID';
            app.Patient_ID_EditField = uieditfield(app.Patient_ID_GridLayout, 'text');
            app.Patient_ID_EditField.Value = 'NPX_MSIT_';
            app.File_Location_GridLayout = uigridlayout(app.Main_GridLayout);
            app.File_Location_Label = uilabel(app.File_Location_GridLayout);
            app.File_Location_Label.Text = 'Save File Location';
            app.File_Location_Display = uilabel(app.File_Location_GridLayout);
            app.File_Location_Display.Text = '';
            app.Change_File_Location_Button = uibutton(app.File_Location_GridLayout, 'push');
            app.Change_File_Location_Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseCSVfile, true);
            app.Change_File_Location_Button.Tag = 'OutputFileLoc';
            app.Change_File_Location_Button.Text = 'Change File Location';
            app.Task_Start_GridLayout = uigridlayout(app.Main_GridLayout);
            app.Task_Start_Button = uibutton(app.Task_Start_GridLayout, 'state');
            app.Task_Start_Button.ValueChangedFcn = createCallbackFcn(app, @StartTheTask, true);
            app.Task_Start_Button.Text = 'Start Task';
            app.ArmCamsWarning_Label = uilabel(app.Task_Start_GridLayout);
            app.ArmCamsWarning_Label.Text = 'ARM CAMERAS BEFORE PRESSING START';
            app.PushOnce_Label = uilabel(app.Task_Start_GridLayout);
            app.PushOnce_Label.Text = '<-- Just push once, takes a long time to load first time';
            app.Text_Settings_GridLayout = uigridlayout(app.Main_GridLayout);
            app.TextSize_Label = uilabel(app.Text_Settings_GridLayout);
            app.TextSize_Label.Text = 'Stimulus Text Size';
            app.TextSize_EditField = uieditfield(app.Text_Settings_GridLayout, 'numeric');
            app.TextSize_EditField.Limits = [8 Inf];
            app.TextSize_EditField.Value = 120;
            app.IntroTextSize_Label = uilabel(app.Text_Settings_GridLayout);
            app.IntroTextSize_Label.Text = 'Intro Text Size';
            app.IntroTextSize_EditField = uieditfield(app.Text_Settings_GridLayout, 'numeric');
            app.IntroTextSize_EditField.Value = 40;
            app.ResponseTime_Label = uilabel(app.Text_Settings_GridLayout);
            app.ResponseTime_Label.Text = 'Response Time (s)';
            app.ResponseTime_EditField = uieditfield(app.Text_Settings_GridLayout, 'numeric');
            app.ResponseTime_EditField.Editable = 'off';
            app.ResponseTime_EditField.Value = 1.75;
            app.Block3_GridLayout = uigridlayout(app.Main_GridLayout);
            app.Block_3_Label = uilabel(app.Block3_GridLayout);
            app.Block_3_Label.Text = 'Block Three';
            app.Block_3_CheckBox = uicheckbox(app.Block3_GridLayout);
            app.Block_3_CheckBox.Text = '';
            app.Block_3_CheckBox.Value = true;
            app.Block_3_FileName = uilabel(app.Block3_GridLayout);
            app.Block_3_FileName.Text = '';
            app.Choose_Block3_File_Button = uibutton(app.Block3_GridLayout, 'push');
            app.Choose_Block3_File_Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseCSVfile, true);
            app.Choose_Block3_File_Button.Tag = 'Block3FileButton';
            app.Choose_Block3_File_Button.Text = 'Choose File';
            app.Block4_GridLayout = uigridlayout(app.Main_GridLayout);
            app.Block_4_Label = uilabel(app.Block4_GridLayout);
            app.Block_4_Label.Text = 'Block Four';
            app.Block_4_CheckBox = uicheckbox(app.Block4_GridLayout);
            app.Block_4_CheckBox.Text = '';
            app.Block_4_FileName = uilabel(app.Block4_GridLayout);
            app.Block_4_FileName.Text = '';
            app.Choose_Block4_File_Button = uibutton(app.Block4_GridLayout, 'push');
            app.Choose_Block4_File_Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseCSVfile, true);
            app.Choose_Block4_File_Button.Tag = 'Block4FileButton';
            app.Choose_Block4_File_Button.Text = 'Choose File';
            app.BlockConfigurationFiles_Label = uilabel(app.Main_GridLayout);
            app.BlockConfigurationFiles_Label.Text = 'Block Configuration Files';
            app.DisplaySettings_Label = uilabel(app.Main_GridLayout);
            app.DisplaySettings_Label.Text = 'Display Settings';
            app.TTL_GridLayout = uigridlayout(app.Main_GridLayout);
            app.TTL_CheckBox = uicheckbox(app.TTL_GridLayout);
            app.TTL_CheckBox.ValueChangedFcn = createCallbackFcn(app, @UseTTL, true);
            app.TTL_CheckBox.Text = '';
            app.TTL_CheckBox.Value = true;
            app.ParallelAddress_Label = uilabel(app.TTL_GridLayout);
            app.ParallelAddress_Label.Enable = 'off';
            app.ParallelAddress_Label.Text = 'Parallel card address';
            app.ParallelAddress_EditField = uieditfield(app.TTL_GridLayout, 'text');
            app.ParallelAddress_EditField.Enable = 'off';
            app.ParallelAddress_EditField.Value = '4FE8';
            app.TTLLength_Label = uilabel(app.TTL_GridLayout);
            app.TTLLength_Label.Enable = 'off';
            app.TTLLength_Label.Text = 'TTL Length (ms)';
            app.TTLLength_EditField = uieditfield(app.TTL_GridLayout, 'numeric');
            app.TTLLength_EditField.Enable = 'off';
            app.TTLLength_EditField.Value = 0.5;
            app.TTLs_Label = uilabel(app.TTL_GridLayout);
            app.TTLs_Label.Text = 'Enable TTLs';
            app.DataIOSettings_Label = uilabel(app.Main_GridLayout);
            app.DataIOSettings_Label.Text = 'Data I/O Settings';
            app.FileSettings_Label = uilabel(app.Main_GridLayout);
            app.FileSettings_Label.Text = 'File Settings';
            app.KB_GridLayout = uigridlayout(app.Main_GridLayout);
            app.RightKey_Label = uilabel(app.KB_GridLayout);
            app.RightKey_Label.Text = 'Response Key 3 (right)';
            app.RightKey = uieditfield(app.KB_GridLayout, 'text');
            app.RightKey.Value = 'd';
            app.MiddleKey_Label = uilabel(app.KB_GridLayout);
            app.MiddleKey_Label.Text = 'Response Key 2 (middle)';
            app.LeftKey_Label = uilabel(app.KB_GridLayout);
            app.LeftKey_Label.Text = 'Response Key 1 (left)';
            app.LeftKey = uieditfield(app.KB_GridLayout, 'text');
            app.LeftKey.Value = 'a';
            app.MiddleKey = uieditfield(app.KB_GridLayout, 'text');
            app.MiddleKey.Value = 's';
            app.KB2_GridLayout = uigridlayout(app.Main_GridLayout);
            app.QuitKey_Label = uilabel(app.KB2_GridLayout);
            app.QuitKey_Label.Text = 'Quit Key';
            app.QuitKey = uieditfield(app.KB2_GridLayout, 'text');
            app.QuitKey.Value = 'w';
            app.AdvanceKey_Label = uilabel(app.KB2_GridLayout);
            app.AdvanceKey_Label.Text = 'Advance Block Key';
            app.AdvanceKey = uieditfield(app.KB2_GridLayout, 'text');
            app.AdvanceKey.Value = 'n';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSIT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end